classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SimilarColourPicker             matlab.ui.control.ColorPicker
        IntersectingLabel_3             matlab.ui.control.Label
        SelectionCountEditField         matlab.ui.control.NumericEditField
        CountEditField_2Label_2         matlab.ui.control.Label
        SelectedColourPicker            matlab.ui.control.ColorPicker
        IntersectingLabel_2             matlab.ui.control.Label
        SelectionLabel                  matlab.ui.control.Label
        IntersectingNeedleColourPicker  matlab.ui.control.ColorPicker
        IntersectingLabel               matlab.ui.control.Label
        NeedleColourPicker              matlab.ui.control.ColorPicker
        NeedlesLabel_2                  matlab.ui.control.Label
        DisplayLabel                    matlab.ui.control.Label
        ResultTextArea                  matlab.ui.control.TextArea
        PlankLengthEditField            matlab.ui.control.NumericEditField
        LengthEditField_2Label          matlab.ui.control.Label
        RunSimulationButton             matlab.ui.control.Button
        NeedlesLabel                    matlab.ui.control.Label
        PlankCountEditField             matlab.ui.control.NumericEditField
        PlanksLabel                     matlab.ui.control.Label
        CountEditFieldLabel             matlab.ui.control.Label
        NeedleCountEditField            matlab.ui.control.NumericEditField
        CountEditField_2Label           matlab.ui.control.Label
        NeedleLengthEditField           matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        ProgramDropDown                 matlab.ui.control.DropDown
        ProgramDropDownLabel            matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % Store recently processed vectors,
        % for RunBuffonStandardPi to process selected needles
        NeedleX1
        NeedleY1
        NeedleX2
        NeedleY2
        NeedleAngle
        CrossingMask
        NeedleLines
    end

    methods (Access = private)
        function RunBuffonStandardPi(app)
            width = app.WidthEditField.Value;
            length = app.NeedleLengthEditField.Value;
            throws = app.NeedleCountEditField.Value;
            planks = app.PlankCountEditField.Value;
            plank_length = app.PlankLengthEditField.Value;

            [p, crossings, centreX, centreY, angle, crossing_mask] = BuffonStandardPi(width, length, throws, planks, plank_length);

            % Limit needles to at most 1000 for plotting
            maxPlot = 1000;
            if throws > maxPlot
                centreX = centreX(1:maxPlot);
                centreY = centreY(1:maxPlot);
                angle = angle(1:maxPlot);
                throws = 1000;
            end

            ax = app.UIAxes;
            cla(ax);

            % Draw plank cracks - width spaced apart
            lines = 0:width:width*planks;
            xline(ax, lines);
            hold(ax, "on");

            halfL = length / 2;
            
            dx = halfL * sin(angle);
            dy = halfL * cos(angle);
            
            x1 = centreX + dx;
            y1 = centreY + dy;
            
            x2 = centreX - dx;
            y2 = centreY - dy;

            % Store for selection logic later
            app.NeedleX1 = x1;
            app.NeedleY1 = y1;
            app.NeedleX2 = x2;
            app.NeedleY2 = y2;
            app.NeedleAngle = angle;
            app.CrossingMask = crossing_mask;
            app.NeedleLines = gobjects(throws, 1);

            % Store each line separately for independent modification in
            % selecting
            for i = 1:throws
                colour = app.NeedleColourPicker.Value;
                if crossing_mask(i)
                    colour = app.IntersectingNeedleColourPicker.Value;
                end
                app.NeedleLines(i) = plot(ax, [x1(i), x2(i)], [y1(i), y2(i)], 'Color', colour, 'HitTest', 'off', 'LineWidth', 1);
            end

            hold(ax, "off");

            app.UIAxes.ButtonDownFcn = @(src, event) app.SelectNeedle(event);

            app.ResultTextArea.Value = sprintf('Estimated π = %.5f\nCrossings = %d', p, crossings);
        end

        function SelectNeedle(app, event)
            click = event.IntersectionPoint;
            cx = click(1); cy = click(2);

            N = length(app.NeedleX1);
            dist = zeros(N, 1);
            for i = 1:N
                x1 = app.NeedleX1(i); y1 = app.NeedleY1(i);
                x2 = app.NeedleX2(i); y2 = app.NeedleY2(i);

                % Vector along the needle
                vx = x2 - x1; vy = y2 - y1;
                % Vector from needle start to click
                wx = cx - x1; wy = cy - y1;

                % Scalar project click vector onto needle vector
                % And clamp to [0,1] to stay bounded within the needle
                % t = dot(w, v) / dot(v, v)
                t = (vx*wx + vy*wy) / (vx*vx + vy*vy);
                t = max(0, min(1, t));

                % Find the closest point on the needle to the click
                px = x1 + t*vx;
                py = y1 + t*vy;

                % And finally, the distance from that point to the click
                dist(i) = hypot(cx - px, cy - py);
            end
        
            % The closest needle of all
            [~, idx] = min(dist);
        
            for i = 1:N
                % Reset color based on crossing
                if app.CrossingMask(i)
                    app.NeedleLines(i).Color = app.IntersectingNeedleColourPicker.Value;
                else
                    app.NeedleLines(i).Color = app.NeedleColourPicker.Value;
                end
                app.NeedleLines(i).LineWidth = 1;
            end
        
            % The selected needle
            app.NeedleLines(idx).Color = app.SelectedColourPicker.Value;
            app.NeedleLines(idx).LineWidth = 2;
        
            n = app.SelectionCountEditField.Value;
            current_angle = app.NeedleAngle(idx);
            other_angles = abs(app.NeedleAngle - current_angle);
            
            % Get the closest needles in angle, excluding the most similar
            % (the already selected needle)
            [~, order] = sort(other_angles);
            similar_idx = order(2:n+1);
        
            for k = similar_idx
                app.NeedleLines(k).Color = app.SimilarColourPicker.Value;
                app.NeedleLines(k).LineWidth = 1.8;
            end
        end

        function DisplaySquares(app, centreX, centreY, angle, crossing_mask)
            width = app.WidthEditField.Value;
            length = app.NeedleLengthEditField.Value;
            throws = app.NeedleCountEditField.Value;
            planks = app.PlankCountEditField.Value;

            % Limit to 1000 for plotting
            maxPlot = 1000;
            if throws > maxPlot
                centreX = centreX(1:maxPlot);
                centreY = centreY(1:maxPlot);
                angle = angle(1:maxPlot);
                throws = 1000;
            end

            ax = app.UIAxes;
            cla(ax);
            hold(ax, "on");
        
            lines = 0:width:width*planks;
            xline(ax, lines);
        
            halfL = length/2;
            corners = halfL * [
                -1, -1;
                 1, -1;
                 1,  1;
                -1,  1
            ];
            
            % The rotation matrix, as an inline function
            R = @(theta) [cos(theta), -sin(theta); sin(theta), cos(theta)];
            
            for i = 1:throws
                % Compute square's individual edges again
                pts = (R(angle(i)) * corners')' + [centreX(i), centreY(i)];
                x = pts(:,1); y = pts(:,2);
            
                xplot = [x; x(1)];
                yplot = [y; y(1)];
            
                if crossing_mask(i)
                    plot(ax, xplot, yplot, 'Color', app.IntersectingNeedleColourPicker.Value);
                else
                    plot(ax, xplot, yplot, 'Color', app.NeedleColourPicker.Value);
                end
            end
        
            hold(ax, "off");
        end

        function RunBuffonSquaresPi(app)
            width = app.WidthEditField.Value;
            length = app.NeedleLengthEditField.Value;
            throws = app.NeedleCountEditField.Value;
            planks = app.PlankCountEditField.Value;
            plank_length = app.PlankLengthEditField.Value;

            [p, crossings, centreX, centreY, angle, crossing_mask] = BuffonSquaresPi(width, length, throws, planks, plank_length);
        
            DisplaySquares(app, centreX, centreY, angle, crossing_mask)
            app.ResultTextArea.Value = sprintf('Estimated π = %.5f\nCrossings = %d', p, crossings);
        end
       
        function RunBuffonSquaresRootTwo(app)
            width = app.WidthEditField.Value;
            length = app.NeedleLengthEditField.Value;
            throws = app.NeedleCountEditField.Value;
            planks = app.PlankCountEditField.Value;
            plank_length = app.PlankLengthEditField.Value;

            [r, crossings, centreX, centreY, angle, crossing_mask] = BuffonSquaresRootTwo(width, length, throws, planks, plank_length);

            DisplaySquares(app, centreX, centreY, angle, crossing_mask)
            app.ResultTextArea.Value = sprintf('Estimated √2 = %.5f\nCrossings = %d', r, crossings);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            program = app.ProgramDropDown.Value;

            switch program
                case 'Standard Pi'
                    RunBuffonStandardPi(app);
                case 'Squares Pi'
                    RunBuffonSquaresPi(app);
                case 'Squares Root 2'
                    RunBuffonSquaresRootTwo(app);
                otherwise
                    error('Unknown program selected.');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ProgramDropDownLabel = uilabel(app.LeftPanel);
            app.ProgramDropDownLabel.Text = 'Program';
            app.ProgramDropDown = uidropdown(app.LeftPanel);
            app.ProgramDropDown.Items = {'Standard Pi', 'Squares Pi', 'Squares Root 2'};
            app.ProgramDropDown.Value = 'Standard Pi';
            app.WidthEditFieldLabel = uilabel(app.LeftPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WidthEditField.Limits = [2 50];
            app.WidthEditField.Value = 10;
            app.LengthEditFieldLabel = uilabel(app.LeftPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.NeedleLengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NeedleLengthEditField.Limits = [1 25];
            app.NeedleLengthEditField.Value = 5;
            app.CountEditField_2Label = uilabel(app.LeftPanel);
            app.CountEditField_2Label.Text = 'Count';
            app.NeedleCountEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NeedleCountEditField.Limits = [1 1000000];
            app.NeedleCountEditField.Value = 500;
            app.CountEditFieldLabel = uilabel(app.LeftPanel);
            app.CountEditFieldLabel.Text = 'Count';
            app.PlanksLabel = uilabel(app.LeftPanel);
            app.PlanksLabel.Text = 'Planks';
            app.PlankCountEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PlankCountEditField.Limits = [1 10];
            app.PlankCountEditField.Value = 5;
            app.NeedlesLabel = uilabel(app.LeftPanel);
            app.NeedlesLabel.Text = 'Needles';
            app.RunSimulationButton = uibutton(app.LeftPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.LengthEditField_2Label = uilabel(app.LeftPanel);
            app.LengthEditField_2Label.Text = 'Length';
            app.PlankLengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PlankLengthEditField.Limits = [1 500];
            app.PlankLengthEditField.Value = 50;
            app.ResultTextArea = uitextarea(app.LeftPanel);
            app.ResultTextArea.Editable = 'off';
            app.DisplayLabel = uilabel(app.LeftPanel);
            app.DisplayLabel.Text = 'Display';
            app.NeedlesLabel_2 = uilabel(app.LeftPanel);
            app.NeedlesLabel_2.Text = 'Needles';
            app.NeedleColourPicker = uicolorpicker(app.LeftPanel);
            app.NeedleColourPicker.Value = [0 0 1];
            app.IntersectingLabel = uilabel(app.LeftPanel);
            app.IntersectingLabel.Text = 'Intersecting';
            app.IntersectingNeedleColourPicker = uicolorpicker(app.LeftPanel);
            app.SelectionLabel = uilabel(app.LeftPanel);
            app.SelectionLabel.Text = 'Selection';
            app.IntersectingLabel_2 = uilabel(app.LeftPanel);
            app.IntersectingLabel_2.Text = 'Selected';
            app.SelectedColourPicker = uicolorpicker(app.LeftPanel);
            app.SelectedColourPicker.Value = [1 1 0];
            app.CountEditField_2Label_2 = uilabel(app.LeftPanel);
            app.CountEditField_2Label_2.Text = 'Count';
            app.SelectionCountEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SelectionCountEditField.Limits = [0 1000];
            app.SelectionCountEditField.Value = 3;
            app.IntersectingLabel_3 = uilabel(app.LeftPanel);
            app.IntersectingLabel_3.Text = 'Similar';
            app.SimilarColourPicker = uicolorpicker(app.LeftPanel);
            app.SimilarColourPicker.Value = [0 1 0];
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Buffon''s Needle Simulation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end